classdef FTIR_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadNewDataButton             matlab.ui.control.Button
        ClearDataButton               matlab.ui.control.Button
        SampleVolumeLEditFieldLabel   matlab.ui.control.Label
        SampleVolumeLEditField        matlab.ui.control.NumericEditField
        SpacerSizemEditFieldLabel     matlab.ui.control.Label
        SpacerSizemEditField          matlab.ui.control.NumericEditField
        TimeIntervalsEditFieldLabel   matlab.ui.control.Label
        TimeIntervalsEditField        matlab.ui.control.NumericEditField
        GasFactorEditFieldLabel       matlab.ui.control.Label
        GasFactorEditField            matlab.ui.control.NumericEditField
        PlotSpectraButton             matlab.ui.control.Button
        CurrentdataLabel              matlab.ui.control.Label
        CurrentdataTextArea           matlab.ui.control.TextArea
        DiffusionCoefficientTextAreaLabel  matlab.ui.control.Label
        DiffusionCoefficientTextArea  matlab.ui.control.TextArea
        CurveFittingPanel             matlab.ui.container.Panel
        FinalConcTextArea             matlab.ui.control.TextArea
        NmaxSpinnerLabel              matlab.ui.control.Label
        NmaxSpinner                   matlab.ui.control.Spinner
        TryFitButton                  matlab.ui.control.Button
        RadiusLabel                   matlab.ui.control.Label
        DiffusionCoeffLabel           matlab.ui.control.Label
        StartPointEditFieldLabel      matlab.ui.control.Label
        StartPointEditFieldR          matlab.ui.control.NumericEditField
        StartPointEditFieldD          matlab.ui.control.NumericEditField
        LowerEditFieldLabel           matlab.ui.control.Label
        LowerEditFieldD               matlab.ui.control.NumericEditField
        UpperEditFieldLabel           matlab.ui.control.Label
        UpperEditFieldD               matlab.ui.control.NumericEditField
        DiffMinChangeEditFieldLabel   matlab.ui.control.Label
        DiffMinChangeEditField        matlab.ui.control.NumericEditField
        DiffMaxChangeEditFieldLabel   matlab.ui.control.Label
        DiffMaxChangeEditField        matlab.ui.control.NumericEditField
        LowerEditFieldR               matlab.ui.control.NumericEditField
        UpperEditFieldR               matlab.ui.control.NumericEditField
        SaturatedConcButtonGroup      matlab.ui.container.ButtonGroup
        SelectOneButton               matlab.ui.control.ToggleButton
        UseLastSpectrumButton         matlab.ui.control.ToggleButton
        GetFinalSpectrumButton        matlab.ui.control.ToggleButton
        SeeFullFitCurveCheckBox       matlab.ui.control.CheckBox
        OtherparametersTextAreaLabel  matlab.ui.control.Label
        OtherparametersTextArea       matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data FTIRexperiment % Description
        nmax (1,1) double
    end
    
    methods (Access = private)
        
        function output = displayData(app)
            output = ["You have "+size(app.data.data,2)+" spectra.";"Sample Volume is "+app.data.volume+" μL.";"Spacer Size is "+app.data.pathLength+" μm.";
                "Gas Factor is "+app.data.gasFactor+".";"Radius is "+string(app.data.radius)+" μm."];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            [spectra,freq] = LoadSpectra();
            app.data = FTIRexperiment(spectra,freq(:,1),0,1,0,"");
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
            app.CurveFittingPanel.Enable = "on";
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.data = FTIRexperiment(0,0,0,1,0,"");
            app.CurrentdataTextArea.Value = "You have no data.";
            cla(app.UIAxes)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            cla(app.UIAxes2)
            app.UIAxes2.YLimMode = 'auto';
            app.SampleVolumeLEditField.Value = 0;
            app.SpacerSizemEditField.Value = 0;
            app.TimeIntervalsEditField.Value = 0;
            app.GasFactorEditField.Value = 0;
            app.PlotSpectraButton.Enable = false;
            app.CurveFittingPanel.Enable = "off";
            app.FinalConcTextArea.Value = "";
            app.DiffusionCoefficientTextArea.Value = "";
            app.NmaxSpinner.Value = 0;
            app.StartPointEditFieldD.Value = 2000;
            app.StartPointEditFieldR.Value = 2000;
            app.LowerEditFieldD.Value = 0;
            app.LowerEditFieldR.Value = 0;
            app.UpperEditFieldD.Value = Inf;
            app.UpperEditFieldR.Value = 2500;
            app.DiffMaxChangeEditField.Value = 1;
            app.DiffMinChangeEditField.Value = 0;
            app.SeeFullFitCurveCheckBox.Value = false;
            app.SeeFullFitCurveCheckBox.Enable = "off";
            app.TryFitButton.Enable = "off";
            app.SaturatedConcButtonGroup.SelectedObject = app.SelectOneButton;
            app.OtherparametersTextArea.Value = "";
        end

        % Button pushed function: PlotSpectraButton
        function PlotSpectraButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            %find the indicies for the amount of spectra desired
            spectraIndicies = zeros(1,10);
            interval = floor(size(app.data.data,2)/10);
            for ii = 0:10-1
                spectraIndicies(ii+1) = 1+(ii*interval);
            end
            if spectraIndicies(end) ~= size(app.data.data,2)
                spectraIndicies = [spectraIndicies size(app.data.data,2)];
            end
            %baseline correction
            baseline = app.data.data - app.data.data(28375,:);
            %gas line correction
            %cd("Users/matthewliberatore/Library/CloudStorage/OneDrive-UniversityofPittsburgh/data/ftir_data/Matt/Gas Lines Ref")
            wha = load("CO2_gas_lines.mat",'gasLines');
            fixed = baseline-app.data.gasFactor.*wha.gasLines;
            plot(app.UIAxes2,app.data.freqAxis,fixed(:,spectraIndicies))
            
            %converts data in f to vector of concentration values of CO2
            %for each spectrum, generates time axis, returns a plot of
            %concentration vs time
            %syntax: plotConcOverTime(f)
            plot(app.UIAxes,timeAxis(app.data),concOverTime(app.data),'color','red');
            app.PlotSpectraButton.Enable = false;
        end

        % Value changed function: SampleVolumeLEditField
        function SampleVolumeLEditFieldValueChanged(app, event)
            app.data.volume = app.SampleVolumeLEditField.Value;
            app.data.radius = sqrt(app.data.volume*1e9/(pi*app.data.pathLength));
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: SpacerSizemEditField
        function SpacerSizemEditFieldValueChanged(app, event)
            app.data.pathLength = app.SpacerSizemEditField.Value;
            app.data.radius = sqrt(app.data.volume*1e9/(pi*app.data.pathLength));
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: TimeIntervalsEditField
        function TimeIntervalsEditFieldValueChanged(app, event)
            app.data.timeInterval = app.TimeIntervalsEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: GasFactorEditField
        function GasFactorEditFieldValueChanged(app, event)
            app.data.gasFactor = app.GasFactorEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: NmaxSpinner
        function NmaxSpinnerValueChanged(app, event)
            value = app.NmaxSpinner.Value;
            app.nmax = value;
            app.TryFitButton.Enable = "on";
            app.SeeFullFitCurveCheckBox.Enable = "off";
        end

        % Callback function: SeeFullFitCurveCheckBox, TryFitButton
        function TryFitButtonPushed(app, event)
            PlotSpectraButtonPushed(app);
            fcnCall = "diffusionModel("+string(app.data.radius)+","+string(app.data.finalConc)+","+string(app.nmax)+",D,r,t)";
            type = fittype(fcnCall,'independent',{'t'},'coefficients',{'D','r'});
            fo = fitoptions(type);
            fo.StartPoint = [app.StartPointEditFieldR.Value app.StartPointEditFieldD.Value];
            fo.Lower = [app.LowerEditFieldD.Value app.LowerEditFieldR.Value];
            fo.Upper = [app.UpperEditFieldD.Value app.UpperEditFieldR.Value];
            fo.DiffMinChange = app.DiffMinChangeEditField.Value;
            fo.DiffMaxChange = app.DiffMaxChangeEditField.Value;
            [testfit,gof] = fit(timeAxis(app.data)',concOverTime(app.data)',type,fo);
            fits = coeffvalues(testfit);
            app.DiffusionCoefficientTextArea.Value = string(fits(1));
            app.OtherparametersTextArea.Value = ["r = "+string(fits(2));"R^2 = "+string(gof.rsquare)];
            hold(app.UIAxes,"on")
            times = timeAxis(app.data);
            if app.SeeFullFitCurveCheckBox.Value == false
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                plot(app.UIAxes,timeAxis(app.data),diffusionModel(app.data.radius,app.data.finalConc,app.nmax,fits(1),fits(2),timeAxis(app.data)),'color','blue')
            elseif app.SeeFullFitCurveCheckBox.Value == true
                app.UIAxes.XLim = [0 4*times(end)];
                app.UIAxes.YLim = [0 app.data.finalConc];
                plot(app.UIAxes,0:0.01:4*times(end),diffusionModel(app.data.radius,app.data.finalConc,app.nmax,fits(1),fits(2),0:0.01:4*times(end)),'color','blue')
            end
            app.TryFitButton.Enable = "off";
            app.SeeFullFitCurveCheckBox.Enable = "on";
        end

        % Selection changed function: SaturatedConcButtonGroup
        function SaturatedConcButtonGroupSelectionChange(app, event)
            selectedButton = app.SaturatedConcButtonGroup.SelectedObject;
            if selectedButton.Text == "Use Last Spectrum"
                app.data.finalSpectrum = app.data.data(:,end);
                app.data = getFinalConc(app.data);
                app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
            elseif selectedButton.Text == "Get Final Spectrum"
                app.data.finalSpectrum = LoadSpectra();
                app.data = getFinalConc(app.data);
                app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
            end
        end

        % Value changed function: DiffMaxChangeEditField, 
        % DiffMinChangeEditField, LowerEditFieldD, LowerEditFieldR, 
        % StartPointEditFieldD, StartPointEditFieldR, 
        % UpperEditFieldD, UpperEditFieldR
        function FitParameterChanged(app, event)
            app.TryFitButton.Enable = "on";
            app.SeeFullFitCurveCheckBox.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadNewDataButton = uibutton(app.UIFigure, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.ClearDataButton = uibutton(app.UIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.SampleVolumeLEditFieldLabel = uilabel(app.UIFigure);
            app.SampleVolumeLEditFieldLabel.Text = 'Sample Volume (μL)';
            app.SampleVolumeLEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleVolumeLEditField.ValueChangedFcn = createCallbackFcn(app, @SampleVolumeLEditFieldValueChanged, true);
            app.SpacerSizemEditFieldLabel = uilabel(app.UIFigure);
            app.SpacerSizemEditFieldLabel.Text = 'Spacer Size (μm)';
            app.SpacerSizemEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpacerSizemEditField.ValueChangedFcn = createCallbackFcn(app, @SpacerSizemEditFieldValueChanged, true);
            app.TimeIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeIntervalsEditFieldLabel.Text = 'Time Interval (s)';
            app.TimeIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervalsEditFieldValueChanged, true);
            app.GasFactorEditFieldLabel = uilabel(app.UIFigure);
            app.GasFactorEditFieldLabel.Text = 'Gas Factor';
            app.GasFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.GasFactorEditField.ValueChangedFcn = createCallbackFcn(app, @GasFactorEditFieldValueChanged, true);
            app.PlotSpectraButton = uibutton(app.UIFigure, 'push');
            app.PlotSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectraButtonPushed, true);
            app.PlotSpectraButton.Enable = 'off';
            app.PlotSpectraButton.Text = 'Plot Spectra';
            app.CurrentdataLabel = uilabel(app.UIFigure);
            app.CurrentdataLabel.Text = 'Current data:';
            app.CurrentdataTextArea = uitextarea(app.UIFigure);
            app.CurrentdataTextArea.Value = {'You have no data.'};
            app.DiffusionCoefficientTextAreaLabel = uilabel(app.UIFigure);
            app.DiffusionCoefficientTextAreaLabel.Text = 'Diffusion Coefficient';
            app.DiffusionCoefficientTextArea = uitextarea(app.UIFigure);
            app.CurveFittingPanel = uipanel(app.UIFigure);
            app.CurveFittingPanel.Enable = 'off';
            app.CurveFittingPanel.Title = 'Curve Fitting';
            app.FinalConcTextArea = uitextarea(app.CurveFittingPanel);
            app.FinalConcTextArea.Value = {''; ''};
            app.NmaxSpinnerLabel = uilabel(app.CurveFittingPanel);
            app.NmaxSpinnerLabel.Text = 'Nmax';
            app.NmaxSpinner = uispinner(app.CurveFittingPanel);
            app.NmaxSpinner.Limits = [0 1000];
            app.NmaxSpinner.ValueChangedFcn = createCallbackFcn(app, @NmaxSpinnerValueChanged, true);
            app.TryFitButton = uibutton(app.CurveFittingPanel, 'push');
            app.TryFitButton.ButtonPushedFcn = createCallbackFcn(app, @TryFitButtonPushed, true);
            app.TryFitButton.Enable = 'off';
            app.TryFitButton.Text = 'Try Fit';
            app.RadiusLabel = uilabel(app.CurveFittingPanel);
            app.RadiusLabel.Text = 'Radius';
            app.DiffusionCoeffLabel = uilabel(app.CurveFittingPanel);
            app.DiffusionCoeffLabel.Text = 'Diffusion Coeff';
            app.StartPointEditFieldLabel = uilabel(app.CurveFittingPanel);
            app.StartPointEditFieldLabel.Text = 'Start Point';
            app.StartPointEditFieldR = uieditfield(app.CurveFittingPanel, 'numeric');
            app.StartPointEditFieldR.Limits = [0 Inf];
            app.StartPointEditFieldR.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.StartPointEditFieldR.Value = 2000;
            app.StartPointEditFieldD = uieditfield(app.CurveFittingPanel, 'numeric');
            app.StartPointEditFieldD.Limits = [0 Inf];
            app.StartPointEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.StartPointEditFieldD.Value = 2000;
            app.LowerEditFieldLabel = uilabel(app.CurveFittingPanel);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditFieldD = uieditfield(app.CurveFittingPanel, 'numeric');
            app.LowerEditFieldD.Limits = [0 Inf];
            app.LowerEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UpperEditFieldLabel = uilabel(app.CurveFittingPanel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditFieldD = uieditfield(app.CurveFittingPanel, 'numeric');
            app.UpperEditFieldD.Limits = [0 Inf];
            app.UpperEditFieldD.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UpperEditFieldD.Value = Inf;
            app.DiffMinChangeEditFieldLabel = uilabel(app.CurveFittingPanel);
            app.DiffMinChangeEditFieldLabel.Text = 'DiffMinChange';
            app.DiffMinChangeEditField = uieditfield(app.CurveFittingPanel, 'numeric');
            app.DiffMinChangeEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.DiffMaxChangeEditFieldLabel = uilabel(app.CurveFittingPanel);
            app.DiffMaxChangeEditFieldLabel.Text = 'DiffMaxChange';
            app.DiffMaxChangeEditField = uieditfield(app.CurveFittingPanel, 'numeric');
            app.DiffMaxChangeEditField.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.DiffMaxChangeEditField.Value = 1;
            app.LowerEditFieldR = uieditfield(app.CurveFittingPanel, 'numeric');
            app.LowerEditFieldR.Limits = [0 Inf];
            app.LowerEditFieldR.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UpperEditFieldR = uieditfield(app.CurveFittingPanel, 'numeric');
            app.UpperEditFieldR.Limits = [0 Inf];
            app.UpperEditFieldR.ValueChangedFcn = createCallbackFcn(app, @FitParameterChanged, true);
            app.UpperEditFieldR.Value = 2500;
            app.SaturatedConcButtonGroup = uibuttongroup(app.CurveFittingPanel);
            app.SaturatedConcButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SaturatedConcButtonGroupSelectionChange, true);
            app.SaturatedConcButtonGroup.Title = 'Saturated Conc';
            app.SelectOneButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.SelectOneButton.Text = 'Select One';
            app.SelectOneButton.Value = true;
            app.UseLastSpectrumButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.UseLastSpectrumButton.Text = 'Use Last Spectrum';
            app.GetFinalSpectrumButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.GetFinalSpectrumButton.Text = 'Get Final Spectrum';
            app.SeeFullFitCurveCheckBox = uicheckbox(app.CurveFittingPanel);
            app.SeeFullFitCurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @TryFitButtonPushed, true);
            app.SeeFullFitCurveCheckBox.Enable = 'off';
            app.SeeFullFitCurveCheckBox.Text = 'See Full Fit Curve';
            app.OtherparametersTextAreaLabel = uilabel(app.UIFigure);
            app.OtherparametersTextAreaLabel.Text = 'Other parameters';
            app.OtherparametersTextArea = uitextarea(app.UIFigure);
            app.OtherparametersTextArea.Value = {''; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration of CO2 vs Time')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'concentration (M)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'FTIR Spectra of the CO2 Peak')
            xlabel(app.UIAxes2, 'frequency (cm^{-1})')
            ylabel(app.UIAxes2, 'absorbance (AU)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [2290 2390];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FTIR_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end